classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BasesButtonGroup  matlab.ui.container.ButtonGroup
        base1             matlab.ui.control.RadioButton
        base2             matlab.ui.control.RadioButton
        base3             matlab.ui.control.RadioButton
        base4             matlab.ui.control.RadioButton
        base5             matlab.ui.control.RadioButton
        PesosPanel        matlab.ui.container.Panel
        aEditFieldLabel   matlab.ui.control.Label
        a                 matlab.ui.control.EditField
        bEditFieldLabel   matlab.ui.control.Label
        b                 matlab.ui.control.EditField
        DrawButton        matlab.ui.control.Button
        PEditFieldLabel   matlab.ui.control.Label
        P                 matlab.ui.control.EditField
        t_0Label          matlab.ui.control.Label
        t_0               matlab.ui.control.EditField
    end

    
    methods (Access = private)
              
        function [f, g, P, a, b, t_0, t_range] = getParams(app)
            if app.base1.Value
                f = @(t) t;
                g = @(t) 1-t;
                t_range = 0:0.0001:1;
            elseif app.base2.Value
                f = @(t) t^2;
                g = @(t) 1-t^2;
                t_range = 0:0.0001:1;
            elseif app.base3.Value
                f = @(t) sin(t/2)^2;
                g = @(t) cos(t/2)^2;
                t_range = 0:0.0001:pi;
            elseif app.base4.Value
                f = @(t) sin((1+t)/2);
                g = @(t) sin((1-t)/2);
                t_range = -1:0.001:1;
            else
                f = @(t) sinh((1+t)/2);
                g = @(t) sinh((1-t)/2);
                t_range = -1:0.001:1;
            end
            
            P = str2num(app.P.Value);
            a = str2num(app.a.Value);
            b = str2num(app.b.Value);
            t_0 = str2num(app.t_0.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.P.Value = '2 1; 0 0; 1 4; 3 0';
            app.a.Value = '1 1 1';
            app.b.Value = '1 3 9';
            app.t_0.Value = '0.2';
        end

        % Button pushed function: DrawButton
        function draw(app, event)
                       
            [f, g, P, a, b, t_0, t_range] = getParams(app);
            casteljau_type_weighted(f, g, P, a, b, t_0, t_range);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BasesButtonGroup = uibuttongroup(app.UIFigure);
            app.BasesButtonGroup.Title = 'Bases';
            app.base1 = uiradiobutton(app.BasesButtonGroup);
            app.base1.Text = 'f(f) = t, g(t) = 1- t';
            app.base1.Value = true;
            app.base2 = uiradiobutton(app.BasesButtonGroup);
            app.base2.Text = 'f(f) = sin(t/2)^2, g(t) = cos(t/2)^2';
            app.base3 = uiradiobutton(app.BasesButtonGroup);
            app.base3.Text = 'f(f) = t^2, g(t) = 1- t^2';
            app.base4 = uiradiobutton(app.BasesButtonGroup);
            app.base4.Text = 'f(f) = sinh((1+t)/2), g(t) = sinh((1-t)/2)';
            app.base5 = uiradiobutton(app.BasesButtonGroup);
            app.base5.Text = 'f(f) = sin((1+t)/2), g(t) = sin((1-t)/2)';
            app.PesosPanel = uipanel(app.UIFigure);
            app.PesosPanel.Title = 'Pesos';
            app.aEditFieldLabel = uilabel(app.PesosPanel);
            app.aEditFieldLabel.Text = 'a';
            app.a = uieditfield(app.PesosPanel, 'text');
            app.bEditFieldLabel = uilabel(app.PesosPanel);
            app.bEditFieldLabel.Text = 'b';
            app.b = uieditfield(app.PesosPanel, 'text');
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @draw, true);
            app.DrawButton.Text = 'Draw';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.P = uieditfield(app.UIFigure, 'text');
            app.t_0Label = uilabel(app.UIFigure);
            app.t_0Label.Text = 't_0';
            app.t_0 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end